classdef RelIntOrthDip_GUI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		PolarisationvsNCorientationUIFigure  matlab.ui.Figure
		GridLayout                matlab.ui.container.GridLayout
		LeftPanel                 matlab.ui.container.Panel
		GridLayout3               matlab.ui.container.GridLayout
		GridLayout10              matlab.ui.container.GridLayout
		G3EditField               matlab.ui.control.NumericEditField
		G3EditFieldLabel          matlab.ui.control.Label
		GridLayout9               matlab.ui.container.GridLayout
		G2EditField               matlab.ui.control.NumericEditField
		G2EditFieldLabel          matlab.ui.control.Label
		G1EditField               matlab.ui.control.NumericEditField
		G1EditFieldLabel          matlab.ui.control.Label
		GridLayout6_2             matlab.ui.container.GridLayout
		dE2EditField              matlab.ui.control.NumericEditField
		DeltaE_2EditFieldLabel    matlab.ui.control.Label
		dE1EditField              matlab.ui.control.NumericEditField
		thetaLabel_2              matlab.ui.control.Label
		GridLayout8               matlab.ui.container.GridLayout
		n2EditField               matlab.ui.control.NumericEditField
		n2EditFieldLabel          matlab.ui.control.Label
		n1EditField               matlab.ui.control.NumericEditField
		n1EditFieldLabel          matlab.ui.control.Label
		NAEditField               matlab.ui.control.NumericEditField
		NAEditFieldLabel          matlab.ui.control.Label
		T_KEditField              matlab.ui.control.NumericEditField
		T_KEditFieldLabel         matlab.ui.control.Label
		GridLayout7               matlab.ui.container.GridLayout
		cEditField                matlab.ui.control.NumericEditField
		cEditFieldLabel           matlab.ui.control.Label
		bEditField                matlab.ui.control.NumericEditField
		bEditFieldLabel           matlab.ui.control.Label
		SetPlaneButton            matlab.ui.control.Button
		PlaneabcLabel             matlab.ui.control.Label
		aEditField                matlab.ui.control.NumericEditField
		aEditFieldLabel           matlab.ui.control.Label
		CalculateButton           matlab.ui.control.Button
		GridLayout6               matlab.ui.container.GridLayout
		PhiEditField              matlab.ui.control.NumericEditField
		PhiEditFieldLabel         matlab.ui.control.Label
		ThetaEditField            matlab.ui.control.NumericEditField
		thetaLabel                matlab.ui.control.Label
		GridLayout5               matlab.ui.container.GridLayout
		StartupButton             matlab.ui.control.Button
		IntensitiesDropDown       matlab.ui.control.DropDown
		IntensitiesDropDownLabel  matlab.ui.control.Label
		NPtsEditField             matlab.ui.control.NumericEditField
		NPtsEditFieldLabel        matlab.ui.control.Label
		RightPanel                matlab.ui.container.Panel
		GridLayout4               matlab.ui.container.GridLayout
		UISpectrum                matlab.ui.control.UIAxes
		UIAxes                    matlab.ui.control.UIAxes
		ContextMenu               matlab.ui.container.ContextMenu
		ExportMenu                matlab.ui.container.Menu
		Menu2                     matlab.ui.container.Menu
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = private)
		h % ViewMode
		UIPol
		UIPol2
		maxs
		NCOr
	end

	methods (Access = private)

		function results = rotate_polvec_matrix(app)

		end

		function u = light_prop_vect(app, theta, phi)
			u_x = sin(theta).*cos(phi);
			u_y = sin(theta).*sin(phi);
			u_z = cos(theta);
			u = [u_x u_y u_z];
		end

		function e = light_pol_vect(app, u)
			e_unnorm = [0; -u(3); u(2)];

			e = e_unnorm./norm(e_unnorm);
		end

		function results = rotate_e_around_u(app, e, u, alpha,phi)
			ux = u(1);
			uy = u(2);
			uz = u(3);

			cs = cos(alpha-phi);
			csm = 1 - cs;
			si = sin(alpha-phi);

			rotation_matrix = ...
				[ cs + ux^2*csm			ux*uy*csm - uz*si		ux*uz*csm + uy*si	;...
				uy*ux*csm +  uz*si	cs + uy^2*csm			uy*uz*csm-ux*si		;...
				uz*ux*csm-uy*si		uz*uy*csm+ux*si			cs + uz^2*csm		];

			results = rotation_matrix*e;
		end


		function results = integrate_over_all_angles(app, e, u, alpha)

			numpts = app.NPtsEditField.Value
			Alphas = linspace(0,2*pi,numpts);
			AllInts = zeros(3,numpts);

			for i = 1:length(Alphas)
				alpha = Alphas(i);
				thise = rotate_e_around_u(app,e,u,alpha,phi);

				AllInts(:,i) = [thise(1)^2 thise(2)^2 thise(3)^2];
			end

		end

		function results = CreateUICube(app)
			cla(app.NCOr)
			my_scale = 0.8;
			my_vertices = [0 0 0; 0 1 0; 1 1 0; 1 0 0; 0 0 1; 0 1 1; 1 1 1; 1 0 1].*my_scale - [ones(8,3).*my_scale./2];
			my_faces = [1 2 3 4; 2 6 7 3; 4 3 7 8; 1 5 8 4; 1 2 6 5; 5 6 7 8];

			pp= patch(app.NCOr,'Vertices', my_vertices, 'Faces', my_faces, 'FaceColor', 'g','FaceAlpha',0.6,'HandleVisibility','off');
			%pp.HandleVisibility='off';
			%pp.HitTest ='on';
			%pp.ButtonDownFcn = @CalculateButtonPushed;
			hold(app.NCOr,'on')
			quiver3(app.NCOr,0,0,0,-my_scale,0,0,'filled','LineWidth',4,'Color',app.NCOr.ColorOrder(1,:),'LineStyle',':','DisplayName',' -X')
			quiver3(app.NCOr,0,0,0,0,-my_scale,0,'filled','LineWidth',4,'Color',app.NCOr.ColorOrder(2,:),'LineStyle',':','DisplayName',' -Y')
			quiver3(app.NCOr,0,0,0,0,0,-my_scale,'filled','LineWidth',4,'Color',app.NCOr.ColorOrder(3,:),'LineStyle',':','DisplayName',' -Z')
			quiver3(app.NCOr,0,0,0,my_scale,0,0,'filled','LineWidth',4,'Color',app.NCOr.ColorOrder(1,:),'DisplayName',' X')
			quiver3(app.NCOr,0,0,0,0,my_scale,0,'filled','LineWidth',4,'Color',app.NCOr.ColorOrder(2,:),'DisplayName',' Y')
			quiver3(app.NCOr,0,0,0,0,0,my_scale,'filled','LineWidth',4,'Color',app.NCOr.ColorOrder(3,:),'DisplayName',' Z')

			pbaspect(app.NCOr,[1 1 1])
			thelim = [-1 1].*my_scale;
			xlim(app.NCOr,thelim); ylim(app.NCOr,thelim); zlim(app.NCOr,thelim);


			xlabel(app.NCOr,'X','Color',app.NCOr.ColorOrder(1,:),'FontWeight','bold','FontSize',15);
			ylabel(app.NCOr,'Y','Color',app.NCOr.ColorOrder(2,:),'FontWeight','bold','FontSize',15);
			zlabel(app.NCOr,'Z','Color',app.NCOr.ColorOrder(3,:),'FontWeight','bold','FontSize',15);


			app.NCOr.XRuler.FirstCrossoverValue  = 0; % X crossover with Y axis
			app.NCOr.YRuler.FirstCrossoverValue  = 0; % Y crossover with X axis
			app.NCOr.ZRuler.FirstCrossoverValue  = 0; % Z crossover with X axis
			app.NCOr.ZRuler.SecondCrossoverValue = 0; % Z crossover with Y axis
			app.NCOr.XRuler.SecondCrossoverValue = 0; % X crossover with Z axis
			app.NCOr.YRuler.SecondCrossoverValue = 0; % Y crossover with Z axis

			set(app.NCOr,'XTick',[],'YTick',[],'ZTick',[])
%			app.h=rotate3d(app.NCOr);%,'on','ActionPostCallback',@GetViewButtonPushed,'UseLegacyExplorationModes',false);
%			app.h.Enable='on';
			%app.h.ButtonDownFilter=@myfunctionfilter;
			
			%assignin('base','hh',app.h)
%assignin('base','app',app)
%			app.h.ActionPostCallback = @mycallback;
			%app.h.UseLegacyExplorationModes='off';
			legend(app.NCOr,'Location','southoutside','NumColumns',2,'EdgeColor','none')
			GUI_fig_children=get(app.PolarisationvsNCorientationUIFigure,'children');
Fig_Axes=findobj(GUI_fig_children,'type','Axes');
fig=figure;ax=axes;clf;
new_handle=copyobj(Fig_Axes,fig);
set(gca,'ActivePositionProperty','outerposition')
set(gca,'Units','normalized')
set(gca,'OuterPosition',[0 0 1 1])
set(gca,'position',[0.1300 0.1100 0.7750 0.8150])
			
		end


		function results = updateView(app)
			%thetavalue = mod(app.ThetaEditField.Value,180);
			%phivalue = mod(app.PhiEditField.Value,360);

			phivalue = app.PhiEditField.Value-270;
			thetavalue = app.ThetaEditField.Value;
			%[phivalue;thetavalue]
			phivalue = mod(phivalue,360);
			newval = phitheta2azel([phivalue;thetavalue],false)	;
			%sprintf('Az %d El %d',newval)
			%newval = light_prop_vect(app, thetavalue, phivalue);

			%			newval(1) = mod(newval(1)
			set(app.NCOr, 'View', newval);
			assignin('base','da',newval)
		end


		function [Alphas, A] = UpdateLinePlot(app)
			theta_view = deg2rad(mod(app.ThetaEditField.Value,180));
			phi_view = deg2rad(mod(app.PhiEditField.Value,360));


			%			theta_view = deg2rad(app.ThetaEditField.Value);
			%			phi_view = deg2rad(app.PhiEditField.Value);


			%theta = pi-theta_view;
			phi = phi_view;
			theta = theta_view;
			%phi = phi_view;

			u = light_prop_vect(app,theta,phi);

			e = light_pol_vect(app,u);

			numpts = app.NPtsEditField.Value;

			Alphas = linspace(0,2*pi,numpts);
			A = zeros(length(Alphas),3);
			for i=1:numpts
				alpha = Alphas(i);
				thise = rotate_e_around_u(app,e,u,alpha,phi);
				A(i,:) = [thise(1).^2 thise(2).^2 thise(3).^2];
			end
			assignin('base','Alphas',Alphas)
			assignin('base','A',A)


			app.maxs=max(A);
Aa=A.*[1 1 1];
			plot(app.UIAxes,rad2deg(Alphas),Aa./max(Aa(:)),'s-','MarkerSize',4)


			polarplot(app.UIPol,Alphas, Aa./max(Aa(:)),'s-','MarkerSize',4)

			%app.UIPol.ThetaZeroLocation = 'top';

			a = getIntensityRatios(app);

Aa=A.*a;
max(Aa)

%%%%% from Boltzmann and rotated vector we get the polarisation measurement

			polarplot(app.UIPol2,Alphas, Aa./max(Aa(:)),'.-')
hold(app.UIPol2,'on')
			polarplot(app.UIPol2,Alphas, sum(Aa,2)./max(sum(Aa,2)),'k-')
			hold(app.UIPol2,'off')
			%app.UIPol2.ThetaZeroLocation = 'top';
			%xlim(app.UIAxes,[-Inf Inf])
			DD = [Aa./max(Aa(:))];

			assignin('base','thisplot',DD)
			updateSpectrum(app)
		end

		function results = updateSpectrum(app)
			cla(app.UISpectrum)
			dE1 = app.dE1EditField.Value;
			dE2 = app.dE2EditField.Value;
			T_K = app.T_KEditField.Value;

			numpts = app.NPtsEditField.Value;


			%%%% prendre en compte le fait que les largeurs sont différentes
			%%%% l'intensité est donnée par les facteurs de boltzmann
			%%%% et on a A1*G1 = I1 et A2*G2 = I2
			%%%% si elles étaient égales alors pas de probleme
			%%%% par contre si on a une raie plus large que l'autre
			%%%% c'est l'intensité intégrée qui doit matcher et pas l'amplitude de
			%%%% la raie

			x = linspace(-2,dE1+dE2+2,numpts);

			a = getIntensityRatios(app);

			%%% la on a les rapports d'intensité
			%%% maintenant les différents pics n'ont pas la meme largeur
			%%% donc les amplitudes sont données par A1==a/G 

			lar1 = app.G1EditField.Value;
			lar2 = app.G2EditField.Value;
			lar3 = app.G3EditField.Value;

			width_factor = [lar1 lar2 lar3]./lar1;

			y1 = lorentzian(a(1)*app.maxs(1)./width_factor(1),0,app.G1EditField.Value,0,x);
			y2 = lorentzian(a(2)*app.maxs(2)./width_factor(2),dE1,app.G2EditField.Value,0,x);
			y3 = lorentzian(a(3)*app.maxs(3)./width_factor(3),dE1+dE2,app.G3EditField.Value,0,x);

			ysum_norm = max(y1+y2+y3);

			plot(app.UISpectrum, x, y1./ysum_norm,'.-')
			hold(app.UISpectrum,'on')
			plot(app.UISpectrum, x, y2./ysum_norm,'.-')
			plot(app.UISpectrum, x, y3./ysum_norm,'.-')
			plot(app.UISpectrum, x, (y1+y2+y3)./ysum_norm,'k-')
		end
		
		function results = getIntensityRatios(app)
			
			dE1 = app.dE1EditField.Value;
			dE2 = app.dE2EditField.Value;
			T_K = app.T_KEditField.Value;

			switch app.IntensitiesDropDown.Value
				case 'equal'
					a1 = 1;
					a2 = 1;
					a3 = 1;
				case 'Boltzmann'
					a1 = 1;
					a2 = exp(-dE1./(1e3*kT(T_K)));
					a3 = exp(-(dE1+dE2)./(1e3*kT(T_K)));
				case '4lvl'
					a1 = 1;
					a2 = 1;
					a3 = 1;
			end

			results = [a1 a2 a3];
		end
		
		function [Alphas, A] = Calculate(app)
						ggg= app.NCOr.View;
			%sprintf('Current view az %d el %d',ggg)
			azel=ggg;
			assignin('base','gg',app.NCOr)
			%azel = reshape(ggg,[2 1]);
			azel(1) = mod(azel(1),360)-180;
			ttmp=azel2phitheta(reshape(azel,[2,1]),false);

			%sprintf('Current view phi %d theta %d',ttmp)
			%ttmp=azel2phitheta([azel(1); azel(2)],false);

			app.PhiEditField.Value = mod(ttmp(1)-270,360);
			app.ThetaEditField.Value = ttmp(2);


			[Alphas, A] = UpdateLinePlot(app);
		end
		
		function [res] = mynewcallback(app,obj, event_obj)
			obj
event_obj.Axes
disp('app')
app.h
						ggg= event_obj.Axes.View
			%sprintf('Current view az %d el %d',ggg)
			azel=ggg;
			%assignin('base','gg',event_obj.Axes.View)
			%azel = reshape(ggg,[2 1]);
			azel(1) = mod(azel(1),360)-180;
			ttmp=azel2phitheta(reshape(azel,[2,1]),false);

			%sprintf('Current view phi %d theta %d',ttmp)
			%ttmp=azel2phitheta([azel(1); azel(2)],false);

			app.PhiEditField.Value = mod(ttmp(1)-270,360)
			app.ThetaEditField.Value = ttmp(2)

			rotate3d(event_obj.Axes,'off')

			Calculate(app)
			res=true;
			assignin('base',zz, app.NCOr) 
			
		end
	end

	methods (Access = public)

	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)


			%%% create polar axes
			app.NCOr = axes(app.GridLayout3);
			app.NCOr.Layout.Row = [1 2];
			app.NCOr.Layout.Column = [1 3];
CreateUICube(app)

			%%% create polar axes
			app.UIPol = polaraxes(app.GridLayout3);
			app.UIPol.ThetaZeroLocation = 'top';
			app.UIPol.Layout.Row = [3 4];
			app.UIPol.Layout.Column = [1 3];


			%%% create polar axes
			app.UIPol2 = polaraxes(app.GridLayout4);
			app.UIPol2.ThetaZeroLocation = 'top';
			app.UIPol2.Layout.Row = [5 8];
			app.UIPol2.Layout.Column = [3 4];
			
			UpdateLinePlot(app);

		end

		% Button pushed function: StartupButton
		function StartupButtonPushed(app, event)
			%startupFcn(app)
			CreateUICube(app)
			
			UpdateLinePlot(app);
		end

		% Value changed function: ThetaEditField
		function ThetaEditFieldValueChanged(app, event)
			%sprintf('Theta %d',app.ThetaEditField.Value)
			updateView(app)
			UpdateLinePlot(app);
		end

		% Value changed function: PhiEditField
		function PhiEditFieldValueChanged(app, event)
			%value = app.PhiEditField.Value;
			%sprintf('Phi %d',app.PhiEditField.Value)
			updateView(app)
			UpdateLinePlot(app);
		end

		% Callback function: CalculateButton, G1EditField, G2EditField, 
		% G3EditField
		function CalculateButtonPushed(app, event)
			Calculate(app);
		end

		% Callback function: SetPlaneButton, aEditField, bEditField, 
		% cEditField
		function SetPlaneButtonPushed(app, event)
			thetarget=[app.aEditField.Value app.bEditField.Value app.cEditField.Value]
			view(app.NCOr,thetarget);

			Calculate(app);
			switch app.IntensitiesDropDown.Value
				case 'equal'
					disp('eqint')
				case 'Boltzmann'
					disp('Bolt')
				case '4lvl'
					disp('4')
			end
		end

		% Value changed function: NPtsEditField
		function NPtsEditFieldValueChanged(app, event)
			value = app.NPtsEditField.Value;
			UpdateLinePlot(app);
		end

		% Callback function
		function UICubeButtonDown(app, event)
			Calculate(app)
		end

		% Key press function: PolarisationvsNCorientationUIFigure
		function PolarisationvsNCorientationUIFigureKeyPress(app, event)
			key = event.Key;
			disp(key)
			switch key
				case 'r'
					Calculate(app);
				case 'R'
					Calculate(app);
			end
		end

		% Value changed function: dE1EditField, dE2EditField
		function dE1EditFieldValueChanged(app, event)
			
			updateSpectrum(app)
		end

		% Value changed function: IntensitiesDropDown
		function IntensitiesDropDownValueChanged(app, event)
			value = app.IntensitiesDropDown.Value;
			app.UISpectrum.Title.String = sprintf('Spectrum with %s intensities',value);
			Calculate(app);
		end

		% Value changed function: T_KEditField
		function T_KEditFieldValueChanged(app, event)
			value = app.T_KEditField.Value;
			Calculate(app);
		end

		% Callback function: ExportMenu, UISpectrum
		function ExportMenuSelected(app, event)
			GUI_fig_children=get(app.PolarisationvsNCorientationUIFigure,'children');
Fig_Axes=findobj(GUI_fig_children,'type','Axes');
fig=figure;ax=axes;clf;
new_handle=copyobj(Fig_Axes,fig);
set(gca,'ActivePositionProperty','outerposition')
set(gca,'Units','normalized')
set(gca,'OuterPosition',[0 0 1 1])
set(gca,'position',[0.1300 0.1100 0.7750 0.8150])
			%GUI_fig_children=get(app.UISpectrum,'children');
Fig_Axes=app.UISpectrum;%findobj(GUI_fig_children,'type','Axes');
fig=figure;ax=axes;clf;
new_handle=copyobj(Fig_Axes,fig);
set(gca,'ActivePositionProperty','outerposition')
set(gca,'Units','normalized')
set(gca,'OuterPosition',[0 0 1 1])
set(gca,'position',[0.1300 0.1100 0.7750 0.8150])
% 			GUI_fig_children=get(,'children');
Fig_Axes=app.UIPol2;%findobj(GUI_fig_children,'type','Axes');
fig=figure;ax=axes;clf;
new_handle=copyobj(Fig_Axes,fig);
set(gca,'ActivePositionProperty','outerposition')
set(gca,'Units','normalized')
set(gca,'OuterPosition',[0 0 1 1])
set(gca,'position',[0.1300 0.1100 0.7750 0.8150])
Fig_Axes=app.UIPol;%findobj(GUI_fig_children,'type','Axes');
fig=figure;ax=axes;clf;
new_handle=copyobj(Fig_Axes,fig);
set(gca,'ActivePositionProperty','outerposition')
set(gca,'Units','normalized')
set(gca,'OuterPosition',[0 0 1 1])
set(gca,'position',[0.1300 0.1100 0.7750 0.8150])
		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.PolarisationvsNCorientationUIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {943, 943};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {498, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.PolarisationvsNCorientationUIFigure = uifigure('Visible', 'off');
			app.PolarisationvsNCorientationUIFigure.Name = 'Polarisation vs. NC orientation';
			app.PolarisationvsNCorientationUIFigure.Icon = 'img.png';
			app.PolarisationvsNCorientationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.PolarisationvsNCorientationUIFigure.KeyPressFcn = createCallbackFcn(app, @PolarisationvsNCorientationUIFigureKeyPress, true);
			app.GridLayout = uigridlayout(app.PolarisationvsNCorientationUIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.GridLayout3 = uigridlayout(app.LeftPanel);
			app.GridLayout5 = uigridlayout(app.GridLayout3);
			app.NPtsEditFieldLabel = uilabel(app.GridLayout5);
			app.NPtsEditFieldLabel.Text = 'NPts';
			app.NPtsEditField = uieditfield(app.GridLayout5, 'numeric');
			app.NPtsEditField.ValueChangedFcn = createCallbackFcn(app, @NPtsEditFieldValueChanged, true);
			app.NPtsEditField.Value = 100;
			app.IntensitiesDropDownLabel = uilabel(app.GridLayout5);
			app.IntensitiesDropDownLabel.Text = 'Intensities';
			app.IntensitiesDropDown = uidropdown(app.GridLayout5);
			app.IntensitiesDropDown.Items = {'equal', 'Boltzmann', '4lvl'};
			app.IntensitiesDropDown.ValueChangedFcn = createCallbackFcn(app, @IntensitiesDropDownValueChanged, true);
			app.IntensitiesDropDown.Value = 'equal';
			app.StartupButton = uibutton(app.GridLayout5, 'push');
			app.StartupButton.ButtonPushedFcn = createCallbackFcn(app, @StartupButtonPushed, true);
			app.StartupButton.Text = 'Startup';
			app.GridLayout6 = uigridlayout(app.GridLayout3);
			app.thetaLabel = uilabel(app.GridLayout6);
			app.thetaLabel.Interpreter = 'latex';
			app.thetaLabel.Text = '$\theta$';
			app.ThetaEditField = uieditfield(app.GridLayout6, 'numeric');
			app.ThetaEditField.Limits = [0 180];
			app.ThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaEditFieldValueChanged, true);
			app.PhiEditFieldLabel = uilabel(app.GridLayout6);
			app.PhiEditFieldLabel.Interpreter = 'latex';
			app.PhiEditFieldLabel.Text = '$\Phi$';
			app.PhiEditField = uieditfield(app.GridLayout6, 'numeric');
			app.PhiEditField.Limits = [0 360];
			app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @PhiEditFieldValueChanged, true);
			app.GridLayout7 = uigridlayout(app.GridLayout3);
			app.CalculateButton = uibutton(app.GridLayout7, 'push');
			app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
			app.CalculateButton.Text = 'Calculate';
			app.aEditFieldLabel = uilabel(app.GridLayout7);
			app.aEditFieldLabel.Text = 'a';
			app.aEditField = uieditfield(app.GridLayout7, 'numeric');
			app.aEditField.ValueChangedFcn = createCallbackFcn(app, @SetPlaneButtonPushed, true);
			app.aEditField.Value = 1;
			app.PlaneabcLabel = uilabel(app.GridLayout7);
			app.PlaneabcLabel.Text = 'Plane [abc]';
			app.SetPlaneButton = uibutton(app.GridLayout7, 'push');
			app.SetPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @SetPlaneButtonPushed, true);
			app.SetPlaneButton.Text = 'Set Plane';
			app.bEditFieldLabel = uilabel(app.GridLayout7);
			app.bEditFieldLabel.Text = 'b';
			app.bEditField = uieditfield(app.GridLayout7, 'numeric');
			app.bEditField.ValueChangedFcn = createCallbackFcn(app, @SetPlaneButtonPushed, true);
			app.cEditFieldLabel = uilabel(app.GridLayout7);
			app.cEditFieldLabel.Text = 'c';
			app.cEditField = uieditfield(app.GridLayout7, 'numeric');
			app.cEditField.ValueChangedFcn = createCallbackFcn(app, @SetPlaneButtonPushed, true);
			app.GridLayout8 = uigridlayout(app.GridLayout3);
			app.T_KEditFieldLabel = uilabel(app.GridLayout8);
			app.T_KEditFieldLabel.Text = 'T_K';
			app.T_KEditField = uieditfield(app.GridLayout8, 'numeric');
			app.T_KEditField.Limits = [0 Inf];
			app.T_KEditField.ValueChangedFcn = createCallbackFcn(app, @T_KEditFieldValueChanged, true);
			app.T_KEditField.Value = 10;
			app.NAEditFieldLabel = uilabel(app.GridLayout8);
			app.NAEditFieldLabel.Text = 'N.A.';
			app.NAEditField = uieditfield(app.GridLayout8, 'numeric');
			app.NAEditField.Limits = [0 Inf];
			app.NAEditField.Value = 0.65;
			app.n1EditFieldLabel = uilabel(app.GridLayout8);
			app.n1EditFieldLabel.Text = 'n1';
			app.n1EditField = uieditfield(app.GridLayout8, 'numeric');
			app.n1EditField.Limits = [0 Inf];
			app.n1EditField.Value = 1.5;
			app.n2EditFieldLabel = uilabel(app.GridLayout8);
			app.n2EditFieldLabel.Text = 'n2';
			app.n2EditField = uieditfield(app.GridLayout8, 'numeric');
			app.n2EditField.Limits = [0 Inf];
			app.n2EditField.Value = 1;
			app.GridLayout6_2 = uigridlayout(app.GridLayout3);
			app.thetaLabel_2 = uilabel(app.GridLayout6_2);
			app.thetaLabel_2.Interpreter = 'latex';
			app.thetaLabel_2.Text = '$\Delta E_1$';
			app.dE1EditField = uieditfield(app.GridLayout6_2, 'numeric');
			app.dE1EditField.Limits = [0 180];
			app.dE1EditField.ValueChangedFcn = createCallbackFcn(app, @dE1EditFieldValueChanged, true);
			app.dE1EditField.Tooltip = {'(meV)'};
			app.dE1EditField.Value = 1;
			app.DeltaE_2EditFieldLabel = uilabel(app.GridLayout6_2);
			app.DeltaE_2EditFieldLabel.Interpreter = 'latex';
			app.DeltaE_2EditFieldLabel.Text = '$\Delta E_2$';
			app.dE2EditField = uieditfield(app.GridLayout6_2, 'numeric');
			app.dE2EditField.Limits = [0 360];
			app.dE2EditField.ValueChangedFcn = createCallbackFcn(app, @dE1EditFieldValueChanged, true);
			app.dE2EditField.Tooltip = {'(meV)'};
			app.dE2EditField.Value = 1;
			app.GridLayout9 = uigridlayout(app.GridLayout3);
			app.G1EditFieldLabel = uilabel(app.GridLayout9);
			app.G1EditFieldLabel.Text = 'G1';
			app.G1EditField = uieditfield(app.GridLayout9, 'numeric');
			app.G1EditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
			app.G1EditField.Value = 1;
			app.G2EditFieldLabel = uilabel(app.GridLayout9);
			app.G2EditFieldLabel.Text = 'G2';
			app.G2EditField = uieditfield(app.GridLayout9, 'numeric');
			app.G2EditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
			app.G2EditField.Value = 1;
			app.GridLayout10 = uigridlayout(app.GridLayout3);
			app.G3EditFieldLabel = uilabel(app.GridLayout10);
			app.G3EditFieldLabel.Text = 'G3';
			app.G3EditField = uieditfield(app.GridLayout10, 'numeric');
			app.G3EditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
			app.G3EditField.Value = 1;
			app.RightPanel = uipanel(app.GridLayout);
			app.GridLayout4 = uigridlayout(app.RightPanel);
			app.UIAxes = uiaxes(app.GridLayout4);
			title(app.UIAxes, 'Polarisation measurement simulation')
			xlabel(app.UIAxes, 'Angle (°)')
			ylabel(app.UIAxes, 'Intensity (a.u.)')
			zlabel(app.UIAxes, 'Z')
			app.UISpectrum = uiaxes(app.GridLayout4);
			title(app.UISpectrum, 'Spectrum with unpolarised detection')
			xlabel(app.UISpectrum, 'E - E_0 (meV)')
			ylabel(app.UISpectrum, 'PL (a.u.)')
			zlabel(app.UISpectrum, 'Z')
			app.UISpectrum.ButtonDownFcn = createCallbackFcn(app, @ExportMenuSelected, true);
			app.ContextMenu = uicontextmenu(app.PolarisationvsNCorientationUIFigure);
			app.ExportMenu = uimenu(app.ContextMenu);
			app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
			app.ExportMenu.Text = 'Export';
			app.Menu2 = uimenu(app.ContextMenu);
			app.Menu2.Text = 'Menu2';
			% Assign app.ContextMenu
			app.GridLayout3.ContextMenu = app.ContextMenu;
			% Show the figure after all components are created
			app.PolarisationvsNCorientationUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = RelIntOrthDip_GUI

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.PolarisationvsNCorientationUIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.PolarisationvsNCorientationUIFigure)
		end
	end
end